classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        EditField_result  matlab.ui.control.EditField
        Label             matlab.ui.control.Label
        Button_recong     matlab.ui.control.Button
        UIAxes            matlab.ui.control.UIAxes
    end


    methods (Access = private)
    
        function result = test(app, test_img,w,b,w_h,b_h)
            x = test_img;
            
            hid = layerout(app, w_h,b_h,x);
            result=layerout(app,w,b,hid);
        end
        
        function output = layerout(app, weights, biases, inputs)
            
            weighted_sum = weights * inputs + biases;
            output = 1 ./ (1 + exp(-weighted_sum));
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_recong
        function Button_recongPushed(app, event)
            [file,path] = uigetfile('*.jpg');
            if isequal(file,0)
                disp('没有选择文件');
                return;
            end
            %disp(['User selected ', fullfile(path,file)]);
            load( 'net.mat');
            I = imread( fullfile(path, file) );
            %test_img=imresize(I, [28, 28])./255;
            if size(I,3)~=1
                I = rgb2gray(I);
            end
            
            I = im2double(I);
            test_img = imresize(I, [28, 28]);
            
            imshow(test_img, 'Parent', app.UIAxes); 
            test_img = reshape(test_img, 28*28,1);
            result = test(app, test_img,w,b,w_h,b_h);
            [~,label] = max(result);
            app.EditField_result.Value = num2str(label-1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '图片展示区')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Button_recong = uibutton(app.UIFigure, 'push');
            app.Button_recong.ButtonPushedFcn = createCallbackFcn(app, @Button_recongPushed, true);
            app.Button_recong.Text = '选择图片';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'识别'; '结果'};
            app.EditField_result = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end